classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        GridLayout5           matlab.ui.container.GridLayout
        TriggerPanel          matlab.ui.container.Panel
        GridLayout6           matlab.ui.container.GridLayout
        TrigValueSlider       matlab.ui.control.Slider
        ValueSliderLabel      matlab.ui.control.Label
        TrigSlopeDropDown     matlab.ui.control.DropDown
        SlopeDropDownLabel    matlab.ui.control.Label
        TrigChannelDropDown   matlab.ui.control.DropDown
        ChannelDropDownLabel  matlab.ui.control.Label
        AutoCaptureButton     matlab.ui.control.Button
        SingleCaptureButton   matlab.ui.control.Button
        GridLayout4           matlab.ui.container.GridLayout
        EditFieldFreq         matlab.ui.control.NumericEditField
        SliderAmp             matlab.ui.control.Slider
        Label_2               matlab.ui.control.Label
        Button1               matlab.ui.control.Button
        GridLayout2           matlab.ui.container.GridLayout
        tsecLabel             matlab.ui.control.Label
        UIAxes_ch4            matlab.ui.control.UIAxes
        UIAxes_ch3            matlab.ui.control.UIAxes
        UIAxes_ch2            matlab.ui.control.UIAxes
        UIAxes_ch1            matlab.ui.control.UIAxes
        UIAxes_src            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        daqDevice
    end
    
    % DAQ related functions
    methods (Access = private)
        
        function daqInit(app)            
            deviceID = "Dev1";

            % configure analog output
            dq = daq("ni");
            addoutput(dq, deviceID, "ao0", "Voltage")
            dq.Rate = dq.RateLimit(2);
            app.daqDevice.ao = dq;

            % configure analog input
            dq = daq("ni");
            ch = addinput(dq, deviceID, "ai0", "Voltage");  ch.TerminalConfig = "Differential";
            ch = addinput(dq, deviceID, "ai1", "Voltage");  ch.TerminalConfig = "Differential";
            ch = addinput(dq, deviceID, "ai2", "Voltage");  ch.TerminalConfig = "Differential";
            ch = addinput(dq, deviceID, "ai3", "Voltage");  ch.TerminalConfig = "Differential";
            ch = addinput(dq, deviceID, "ai4", "Voltage");  ch.TerminalConfig = "Differential";
            dq.Rate = dq.RateLimit(2);
            app.daqDevice.ai = dq;

            % configure analog output
            dq = daq("ni");
            addoutput(dq, deviceID, "port1/line7", "Digital")
            app.daqDevice.relay = dq;
        end

        function daqSetRelay(app, bool)
            write(app.daqDevice.relay, logical(bool))
        end

        function daqGenerateAnalogOutput(app, freq, amplitude)
            stop(app.daqDevice.ao)
            samples = app.daqDevice.ao.Rate;
            data = amplitude * sin(linspace(0, 2*pi*freq, samples)');
            preload(app.daqDevice.ao, data)
            start(app.daqDevice.ao, "repeatoutput")
        end

        function daqStopAnalogOutput(app)
            stop(app.daqDevice.ao)
            write(app.daqDevice.ao, 0)
        end

        function [t,src,ch1,ch2,ch3,ch4] = daqReadAnalogInput(app, span)
            [data,t,~] = read(app.daqDevice.ai, span, "OutputFormat","Matrix");
            src = data(:,1);
            ch1 = data(:,2);
            ch2 = data(:,3);
            ch3 = data(:,4);
            ch4 = data(:,5);
        end
    end

    % processing related functions
    methods (Access = private)

        function captureAndDisplayData(app)
            span = seconds(0.01);

            [t,src,ch1,ch2,ch3,ch4] = app.daqReadAnalogInput(span);
            plot(app.UIAxes_src, t, src)
            plot(app.UIAxes_ch1, t, ch1)
            plot(app.UIAxes_ch2, t, ch2)
            plot(app.UIAxes_ch3, t, ch3)
            plot(app.UIAxes_ch4, t, ch4)

            i = findTriggerStart(app, src,ch1,ch2,ch3,ch4);
            if isempty(i)
                i = 1;
            end

            t = t(i:end) - t(i);

            plot(app.UIAxes_src, t, src(i:end))
            plot(app.UIAxes_ch1, t, ch1(i:end))
            plot(app.UIAxes_ch2, t, ch2(i:end))
            plot(app.UIAxes_ch3, t, ch3(i:end))
            plot(app.UIAxes_ch4, t, ch4(i:end))

            lim = [0, seconds(span)];
            xlim(app.UIAxes_src, lim)
            xlim(app.UIAxes_ch1, lim)
            xlim(app.UIAxes_ch2, lim)
            xlim(app.UIAxes_ch3, lim)
            xlim(app.UIAxes_ch4, lim)
        end

        function start = findTriggerStart(app, src,ch1,ch2,ch3,ch4)
            channel = app.TrigChannelDropDown.Value;
            if     strcmpi(channel, 'Source'),    y = src;
            elseif strcmpi(channel, 'Channel 1'), y = ch1;
            elseif strcmpi(channel, 'Channel 2'), y = ch2;
            elseif strcmpi(channel, 'Channel 3'), y = ch3;
            elseif strcmpi(channel, 'Channel 4'), y = ch4;
            else, start = []; return;
            end

            triggerValue = app.TrigValueSlider.Value;

            triggerSlope = lower(app.TrigSlopeDropDown.Value);
            start_candidate = [];
            if contains(triggerSlope, 'rising')
                mask = y >= triggerValue;
                mask(1) = 0;
                idx2 = find(mask);
                idx = idx2(y(idx2 - 1) < triggerValue);
                start_candidate = [start_candidate; idx(:)];
            end
            if contains(triggerSlope, 'falling')
                mask = y <= triggerValue;
                mask(1) = 0;
                idx2 = find(mask);
                idx = idx2(y(idx2 - 1) > triggerValue);
                start_candidate = [start_candidate; idx(:)];
            end
            start = min(start_candidate);
        end

        function a = foo(app)
            a = '1927';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.daqInit()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.daqStopAnalogOutput()
            delete(app)
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            if strcmpi(app.Button1.Text, "Generate signal")
                freq = app.EditFieldFreq.Value;
                amplitude = app.SliderAmp.Value;
                app.daqGenerateAnalogOutput(freq, amplitude);
                app.Button1.Text = "Stop signal";
            else
                app.daqStopAnalogOutput();
                app.Button1.Text = "Generate signal";
            end
        end

        % Button pushed function: SingleCaptureButton
        function SingleCaptureButtonPushed(app, event)
            app.captureAndDisplayData();
        end

        % Button pushed function: AutoCaptureButton
        function AutoCaptureButtonPushed(app, event)
            myTimer = timer('ExecutionMode','fixedSpacing', 'TasksToExecute',Inf);
            myTimer.TimerFcn = @(~,~)app.captureAndDisplayData();
            start(myTimer)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes_src = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_src, 'Source', 'Interpreter', 'latex')
            app.UIAxes_ch1 = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_ch1, 'Channel 1', 'Interpreter', 'latex')
            app.UIAxes_ch2 = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_ch2, 'Channel 2', 'Interpreter', 'latex')
            app.UIAxes_ch3 = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_ch3, 'Channel 3', 'Interpreter', 'latex')
            app.UIAxes_ch4 = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_ch4, 'Channel 4', 'Interpreter', 'latex')
            app.tsecLabel = uilabel(app.GridLayout2);
            app.tsecLabel.Interpreter = 'latex';
            app.tsecLabel.Text = '$t$ (sec)';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Button1 = uibutton(app.GridLayout4, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = 'Generate signal';
            app.Label_2 = uilabel(app.GridLayout4);
            app.Label_2.Text = '';
            app.SliderAmp = uislider(app.GridLayout4);
            app.SliderAmp.Limits = [0 10];
            app.SliderAmp.Value = 1;
            app.EditFieldFreq = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldFreq.Limits = [10 10000];
            app.EditFieldFreq.Value = 1000;
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.SingleCaptureButton = uibutton(app.GridLayout5, 'push');
            app.SingleCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @SingleCaptureButtonPushed, true);
            app.SingleCaptureButton.Text = 'Single';
            app.AutoCaptureButton = uibutton(app.GridLayout5, 'push');
            app.AutoCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @AutoCaptureButtonPushed, true);
            app.AutoCaptureButton.Text = 'Run';
            app.TriggerPanel = uipanel(app.GridLayout5);
            app.TriggerPanel.Title = 'Trigger';
            app.GridLayout6 = uigridlayout(app.TriggerPanel);
            app.ChannelDropDownLabel = uilabel(app.GridLayout6);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.TrigChannelDropDown = uidropdown(app.GridLayout6);
            app.TrigChannelDropDown.Items = {'Source', 'Channel 1', 'Channel 2', 'Channel 3', 'Channel 4', 'None'};
            app.TrigChannelDropDown.Value = 'Source';
            app.SlopeDropDownLabel = uilabel(app.GridLayout6);
            app.SlopeDropDownLabel.Text = 'Slope';
            app.TrigSlopeDropDown = uidropdown(app.GridLayout6);
            app.TrigSlopeDropDown.Items = {'Rising', 'Falling', 'Rising or Falling'};
            app.TrigSlopeDropDown.Value = 'Rising';
            app.ValueSliderLabel = uilabel(app.GridLayout6);
            app.ValueSliderLabel.Text = 'Value';
            app.TrigValueSlider = uislider(app.GridLayout6);
            app.TrigValueSlider.Limits = [-10 10];
            app.TrigValueSlider.MajorTicks = [-9 -6 -3 0 3 6 9];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end